classdef Validation_app_Final_v3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        Tab                             matlab.ui.container.Tab
        TimeserieserrorquantificationTab  matlab.ui.container.Tab
        SelectOrganismButton            matlab.ui.control.Button
        Species_label                   matlab.ui.control.Label
        StartneweffortCheckBox          matlab.ui.control.CheckBox
        ContinueexistingeffortCheckBox  matlab.ui.control.CheckBox
        CorrectclassificationPanel      matlab.ui.container.Panel
        YESYButton                      matlab.ui.control.Button
        NOother0Button                  matlab.ui.control.Button
        NOcantidentifyXButton           matlab.ui.control.Button
        outofLabel                      matlab.ui.control.Label
        GoBackButton                    matlab.ui.control.Button
        SelectpathtoimagefoldersButton  matlab.ui.control.StateButton
        ImagesleftEditField_2Label      matlab.ui.control.Label
        ImagesleftEditField             matlab.ui.control.NumericEditField
        ImagePanel                      matlab.ui.container.Panel
        Image                           matlab.ui.control.Image
        Img_ID                          matlab.ui.control.Label
        PhytoplanktonidentificationTab  matlab.ui.container.Tab
        SelectpathtotheimagefolderButton  matlab.ui.control.StateButton
        ImageclassificationPanel        matlab.ui.container.Panel
        Other0Button                    matlab.ui.control.Button
        CantidentifyXButton             matlab.ui.control.Button
        SpecifyButton                   matlab.ui.control.Button
        LingulodiniumLButton            matlab.ui.control.Button
        DinophysisDButton               matlab.ui.control.Button
        AkashiwoAButton                 matlab.ui.control.Button
        CiliatesSButton                 matlab.ui.control.Button
        NaupliiNButton                  matlab.ui.control.Button
        ChattonellaHButton              matlab.ui.control.Button
        CeratiumfurcaFButton            matlab.ui.control.Button
        CochlodiniumCButton             matlab.ui.control.Button
        GyrodiniumGButton               matlab.ui.control.Button
        ProrocentrumPButton             matlab.ui.control.Button
        PolikrikosKButton               matlab.ui.control.Button
        PseudonitzshiaZButton           matlab.ui.control.Button
        ZooplanktonidentificationTab    matlab.ui.container.Tab
        ImageclassificationPanel_ZOO    matlab.ui.container.Panel
        Other0Button_2                  matlab.ui.control.Button
        CantidentifyXButton_2           matlab.ui.control.Button
        SpecifyButton_2                 matlab.ui.control.Button
        CalanoidAButton                 matlab.ui.control.Button
        Euphausiid5Button               matlab.ui.control.Button
        Copepod4Button                  matlab.ui.control.Button
        Amphipod1Button                 matlab.ui.control.Button
        Appendicularian2Button          matlab.ui.control.Button
        Cladoceran3Button               matlab.ui.control.Button
        CyclopoidBButton                matlab.ui.control.Button
        HarpacticoidCButton             matlab.ui.control.Button
    end

    
    properties (Access = public)
        Handles  % Description
        userName
        outfile1
        OutputPath
        Path2folder
        Path2Image
        ValidFolders
        SelectedSpecies
        tmp_filename
    end
  
    methods (Access = public)
        
        function displayNext(app)
            if app.Handles.flag <= length(app.Handles.fileList)
                app.Image.ImageSource = char(strcat(app.Path2Image,filesep,app.Handles.fileList(app.Handles.ind(app.Handles.flag))));
                app.ImagesleftEditField.Value=app.Handles.ImgLeft-app.Handles.flag+1;
                app.Img_ID.Text=strcat('Image ID:',{' '}, app.Handles.fileList(app.Handles.ind(app.Handles.flag)));
                app.ImagePanel.Visible = 'on';
                app.outofLabel.Visible = 'on';
                app.ImagesleftEditField_2Label.Visible = 'on';
                app.ImagesleftEditField.Visible = 'on';
                app.GoBackButton.Visible = 'on';
            else
                msgbox('Well done! All images have been annotated!','Success!');
                resetScreen(app)
                app.SelectOrganismButton.Visible='on';
            end
            guidata(app.UIFigure, app.Handles)
        end
    end
    
    
    methods (Access = public)
        
        function SaveOutput(app)
            img_save=[char(app.Handles.fileList(app.Handles.ind(app.Handles.flag))),app.Handles.class];   
 
            fid=fopen(char(strcat(app.OutputPath,app.outfile1)), 'a');
            fprintf(fid, '%s,%s\n', img_save{:});
            fclose(fid);
        end
    end
    
    
    methods (Access = private)
        
        function resetScreen(app)
            app.SelectOrganismButton.Visible='off';
            app.Species_label.Text=' ';
            app.StartneweffortCheckBox.Visible = 'off';
            app.StartneweffortCheckBox.Value = false;
            app.ContinueexistingeffortCheckBox.Visible = 'off';
            app.ContinueexistingeffortCheckBox.Value = false;        
            app.StartneweffortCheckBox.FontColor = 'k';
            app.ContinueexistingeffortCheckBox.FontColor = 'k';
            app.CorrectclassificationPanel.Visible = 'off';
            app.outofLabel.Visible = 'off';
            app.ImagesleftEditField.Visible = 'off';
            app.ImagesleftEditField_2Label.Visible='off';
            app.ImagePanel.Visible = 'off';
            app.GoBackButton.Visible = 'off';        
            app.SelectpathtoimagefoldersButton.Visible='off';

            app.SelectpathtotheimagefolderButton.Visible='off';
            app.ImageclassificationPanel.Visible='off';
            app.ImageclassificationPanel_ZOO.Visible='off';
            
        end
        
        
        function resetComponents(app)
            app.Image.ImageSource='';
            app.Handles=[];
        end
        
        function AfterClassButtonPressed(app)
            SaveOutput(app)
            
            %switch to next figure
            app.Handles.flag = app.Handles.flag + 1;
            displayNext(app)
            guidata(app.UIFigure, app.Handles) 
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
          movegui(app.UIFigure,"center");
          
        end

        % Button pushed function: SelectOrganismButton
        function SelectOrganismButtonPushed(app, event)
             % Choose default command line output for classify_cuttles
            app.Handles.output = app.UIFigure;
          %======================================================================
            % Start up code
           clear global;        
           resetScreen(app)
           resetComponents(app)
           app.SelectOrganismButton.Visible='on';

            spaceList=cell(1,size(app.ValidFolders,1));
            for i=1:size(app.ValidFolders,1)
                spaceList(i)=cellstr(app.ValidFolders(i).name);
            end          
            
            fld_names = spaceList;

            [idx, tf] = listdlg('ListString', spaceList,...
                'SelectionMode', 'Single', 'PromptString', 'Select item', 'Initialvalue', 1,'Name', 'Make choice');
           
            if tf
                space = spaceList{idx}; 
                app.Species_label.Text = strcat('Validating:',{' '},space);
                app.Handles.organismID=fld_names{idx};
                app.StartneweffortCheckBox.Visible = 'on';
                app.ContinueexistingeffortCheckBox.Visible = 'on';

      %          app.CorrectclassificationPanel.Visible = 'on';  app.Handles.output = app.UIFigure;
            else
                % user canceled or closed dialog
            end
            
            app.SelectedSpecies=fld_names(idx);
            app.Path2Image=strcat(app.Path2folder,filesep,app.SelectedSpecies);
            
            %create a list of paths to ALL images in the chosen class (across all daily folders) 
            tdir=strcat(app.Path2folder,filesep,app.SelectedSpecies);
            image_list=dir(fullfile(char(tdir),'*.jpg'));
            clist=cell(size(image_list,1),1); %generate class list of images
            for j=1:size(image_list,1)
                clist(j)=cellstr(image_list(j).name);
            end
            app.Handles.fileList=clist;
            app.Handles.TotalImgs=size(image_list,1);
                    
            % Define a flag to keep track of the image index
            app.Handles.flag = 0;
            
            app.outofLabel.Text = strcat('out of',{' '},num2str(app.Handles.TotalImgs));
          
%             % Create space to store indicies of classified images
%             app.Handles.yesbutt = [];
%             app.Handles.nobutt = [];
%             app.Handles.notsure = [];
            
%             % Empty matrix for times
%             app.Handles.time = [];
            app.Handles.startTime = clock;
            startTime=app.Handles.startTime;
            app.tmp_filename=[app.Handles.organismID,'_labels','_date',sprintf('%04d',startTime(1)),sprintf('%02d',startTime(2)),sprintf('%02d',startTime(3)),'_'];

%             b = clock;
%             app.Handles.time = [app.Handles.time; 'start ', sprintf('%d_%d_%d_%d_%d_%d',b(1),b(2),b(3),b(4),b(5),b(6))];
                        
            % Display the first image
%             displayNext(app)
            
            % Update app.Handles structure
            guidata(app.UIFigure, app.Handles);
            
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            if strcmp(event.Key,'leftarrow')
                GoBackButtonPushed(app,event)
            else
                ClassButtonPushed(app,event)                       
            end

        end

        % Value changed function: StartneweffortCheckBox
        function StartneweffortCheckBoxValueChanged(app, event)
        value = app.StartneweffortCheckBox.Value; 

            if value==1
                 app.userName = inputdlg('Please enter your name for the file name');
                 
                 if isempty(app.userName)
                     app.StartneweffortCheckBox.Value=0; 

                 else
                     app.outfile1=[app.tmp_filename,app.userName{1},'.csv'];
                     
                     if app.TabGroup.SelectedTab==app.PhytoplanktonidentificationTab || app.TabGroup.SelectedTab==app.ZooplanktonidentificationTab
                         tmp=regexp(app.Path2folder,filesep,'split');
                         tmp2=join(tmp(1:end-1),filesep);
                         app.OutputPath=strcat(tmp2,filesep); 
                         clear tmp tmp2
                     else
                         app.OutputPath=strcat(app.Path2folder,filesep);
                     end
                     
                  
                     fid=fopen(char(strcat(app.OutputPath,app.outfile1)), 'w');
                     headers={'Image ID','Label'};
                     fprintf(fid, '%s,%s\n', headers{:});
                     fclose(fid);
                     
                     % Randomize image index
                     app.Handles.ind = randperm(length(app.Handles.fileList));
                     app.Handles.ImgLeft=app.Handles.TotalImgs;
                 
                     app.ContinueexistingeffortCheckBox.Visible = 'off';
                     app.StartneweffortCheckBox.FontColor = [0.502 0.502 0.502];
   
                     if app.TabGroup.SelectedTab==app.TimeserieserrorquantificationTab
                        app.CorrectclassificationPanel.Visible = 'on';
                     end
                     
                    if app.TabGroup.SelectedTab==app.PhytoplanktonidentificationTab
                        app.ImageclassificationPanel.Visible = 'on';
                    end
                    
                    if app.TabGroup.SelectedTab==app.ZooplanktonidentificationTab
                        app.ImageclassificationPanel_ZOO.Visible = 'on';
                     end
                  
                      % Display the first image
                     app.Handles.flag = 1;
                     displayNext(app)
                     
                     
                 end
            end
        end

        % Value changed function: ContinueexistingeffortCheckBox
        function ContinueexistingeffortCheckBoxValueChanged(app, event)
        value = app.ContinueexistingeffortCheckBox.Value; 

            if value==1
               
                 %Load IDs of already annotated images from the file and exclude from the image list 
                 if app.TabGroup.SelectedTab==app.TimeserieserrorquantificationTab
                    [file,path] = uigetfile(strcat(app.SelectedSpecies,'*.csv'));
                 end
                 
                 if app.TabGroup.SelectedTab==app.PhytoplanktonidentificationTab || app.TabGroup.SelectedTab==app.ZooplanktonidentificationTab
                    tmp=regexp(app.Path2folder,filesep,'split');
                    folder_id=tmp(end);
                    [file,path] = uigetfile(strcat(char(folder_id),'*.csv'));
                    clear folder_id tmp
                 end       
                                  
                 if file==0
                     app.ContinueexistingeffortCheckBox.Value=0; 
                 else
                     app.outfile1=file;
                     app.OutputPath=path;
                     
                     %remove already annotated images from the list of all
                     %images to annotate
                    output=readtable(char(strcat(app.OutputPath,app.outfile1)),'preservevariablenames',1);   
                    img=table2array(output(:,'Image ID'));
                    if size(img,1)>size(app.Handles.fileList,1)
                        warndlg('Number of entries in the file exceeds the total number of images!')
                    end
                    
                    if ~isempty(img)
                        [a,~]=ismember(app.Handles.fileList,img);
                        app.Handles.fileList(a)=[];
                    end
                    app.Handles.ImgLeft=size(app.Handles.fileList,1);
                     
                    % Randomize image index
                    app.Handles.ind = randperm(length(app.Handles.fileList));
                    
                    app.StartneweffortCheckBox.Visible = 'off';
                    app.ContinueexistingeffortCheckBox.FontColor = [0.502 0.502 0.502];

                    if app.TabGroup.SelectedTab==app.TimeserieserrorquantificationTab
                        app.CorrectclassificationPanel.Visible = 'on';
                    end
                     
                     if app.TabGroup.SelectedTab==app.PhytoplanktonidentificationTab
                        app.ImageclassificationPanel.Visible = 'on';
                     end
                     
                     if app.TabGroup.SelectedTab==app.ZooplanktonidentificationTab
                        app.ImageclassificationPanel_ZOO.Visible = 'on';
                     end
                     
                    % Display the first image
                   app.Handles.flag = 1;
                   displayNext(app)              
                     
                 end
            end
        end

        % Button pushed function: GoBackButton
        function GoBackButtonPushed(app, event)
        if app.Handles.flag>1
            fid=fopen(char(strcat(app.OutputPath,app.outfile1)),'r');
            tmp=textscan(fid,'%s %s %s %s','delimiter',',');
            fclose(fid);
            
            tmp = [tmp{:}];
            tmp(size(tmp,1),:)=[];
            tmp=tmp';
            
            fid=fopen(char(strcat(app.OutputPath,app.outfile1)),'w');
            fprintf(fid, '%s,%s,%s,%s\n', tmp{:});
            fclose(fid);
            
            app.Handles.flag = app.Handles.flag - 1;
            displayNext(app)
        end
        end

        % Value changed function: SelectpathtoimagefoldersButton
        function SelectpathtoimagefoldersButtonValueChanged(app, event)
      %      value = app.SelectpathtoimagefoldersButton.Value;
        app.Path2folder = uigetdir('Specify folder with all ROIs');
        
        if app.Path2folder==0
            resetScreen(app)
            app.SelectpathtoimagefoldersButton.Visible='on';            
        else
 
        key = questdlg(sprintf('%s\n%s', 'Select directory containing species-specific folders:',app.Path2folder),...
	'Select path', ...
	'Re-select','Confirm','Confirm'); 
           
        while strcmp(key,'Re-select')
             app.Path2folder = uigetdir('Specify folder with all ROIs');
             key = questdlg(sprintf('%s\n%s', 'Select directory containing species-specific folders:',app.Path2folder),...
        	'Select path', ...
        	'Re-select','Confirm','Confirm'); 
        end
        
            if strcmp(key,'Confirm')
                folders=dir(app.Path2folder);
                folders(ismember( {folders.name}, {'.', '..'})) = [];
                dirFlags = [folders.isdir];
                app.ValidFolders = folders(dirFlags);
                resetScreen(app)
                 app.SelectOrganismButton.Visible = 'on';
                 app.Species_label.Visible = 'on';

            end
            
            if isempty(key)
                resetScreen(app)
                app.SelectpathtoimagefoldersButton.Visible='on';            
            end    


        end
        end

        % Value changed function: SelectpathtotheimagefolderButton
        function SelectpathtotheimagefolderButtonValueChanged(app, event)
         %   value = app.SelectpathtotheimagefolderButton.Value;
        app.Handles.output = app.UIFigure;
        app.Path2folder = uigetdir('Specify folder with all ROIs');
 
         if app.Path2folder==0
            resetScreen(app)
            app.SelectpathtotheimagefolderButton.Visible='on';            
         else
             
        key = questdlg(sprintf('%s\n%s', 'Select directory to the image folder:',app.Path2folder),...
	'Select path', ...
	'Re-select','Confirm','Confirm'); 


        while strcmp(key,'Re-select')
             app.Path2folder = uigetdir('Specify folder with all ROIs');
             key = questdlg(sprintf('%s\n%s', 'Select directory to the image folder:',app.Path2folder),...
        	'Select path', ...
        	'Re-select','Confirm','Confirm'); 
        end
        
            if strcmp(key,'')
                resetScreen(app)
                app.SelectpathtotheimagefolderButton.Visible='on';            
            end
            
            if strcmp(key,'Confirm')
                image_list=dir(fullfile(char(app.Path2folder),'*.jpg'));
                clist=cell(size(image_list,1),1); %generate class list of images
                for j=1:size(image_list,1)
                    clist(j)=cellstr(image_list(j).name);
                end
                app.Handles.fileList=clist;
                app.Handles.TotalImgs=size(image_list,1);
                        
                % Define a flag to keep track of the image index
                app.Handles.flag = 0;
                app.Path2Image=app.Path2folder;
                tmp=regexp(app.Path2folder,filesep,'split');
                folder_id=tmp(end);
    
                app.outofLabel.Text = strcat('out of',{' '},num2str(app.Handles.TotalImgs));
    
                app.StartneweffortCheckBox.Visible = 'on';
                app.ContinueexistingeffortCheckBox.Visible = 'on';
                app.SelectpathtotheimagefolderButton.Visible = 'off';
    
                app.Handles.startTime = clock;
                startTime=app.Handles.startTime;
                
               if app.TabGroup.SelectedTab==app.PhytoplanktonidentificationTab              
                   app.tmp_filename=[char(folder_id),'_PHYTOidentification_date',sprintf('%04d',startTime(1)),sprintf('%02d',startTime(2)),sprintf('%02d',startTime(3)),'_'];
               end
                
               if app.TabGroup.SelectedTab==app.ZooplanktonidentificationTab              
                   app.tmp_filename=[char(folder_id),'_ZOOidentification_date',sprintf('%04d',startTime(1)),sprintf('%02d',startTime(2)),sprintf('%02d',startTime(3)),'_'];
               end
    
                guidata(app.UIFigure, app.Handles);
            end
         end
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            %selectedTab = app.TabGroup.SelectedTab;
            resetScreen(app)
            resetComponents(app)
            app.SelectpathtoimagefoldersButton.Visible='on';
            app.SelectpathtotheimagefolderButton.Visible='on';
            
            if app.TabGroup.SelectedTab==app.TimeserieserrorquantificationTab
                app.ImagePanel.Parent=app.TimeserieserrorquantificationTab;
                app.outofLabel.Parent=app.TimeserieserrorquantificationTab;
                app.ImagesleftEditField.Parent=app.TimeserieserrorquantificationTab;
                app.ImagesleftEditField_2Label.Parent=app.TimeserieserrorquantificationTab;
                app.GoBackButton.Parent=app.TimeserieserrorquantificationTab;
                
                app.StartneweffortCheckBox.Parent=app.TimeserieserrorquantificationTab;
                app.StartneweffortCheckBox.Position = [714 468 143 22];
                app.ContinueexistingeffortCheckBox.Parent=app.TimeserieserrorquantificationTab;
                app.ContinueexistingeffortCheckBox.Position = [896 468 207 22];

            end

            if app.TabGroup.SelectedTab==app.PhytoplanktonidentificationTab
                app.ImagePanel.Parent=app.PhytoplanktonidentificationTab;
                app.outofLabel.Parent=app.PhytoplanktonidentificationTab;
                app.ImagesleftEditField.Parent=app.PhytoplanktonidentificationTab;
                app.ImagesleftEditField_2Label.Parent=app.PhytoplanktonidentificationTab;
                app.GoBackButton.Parent=app.PhytoplanktonidentificationTab;

                app.SelectpathtotheimagefolderButton.BackgroundColor = [0.651 0.7804 0.8627];
                app.SelectpathtotheimagefolderButton.Parent=app.PhytoplanktonidentificationTab;
               
                app.StartneweffortCheckBox.Parent=app.PhytoplanktonidentificationTab;
                app.StartneweffortCheckBox.Position = [723 605 143 22];
                app.ContinueexistingeffortCheckBox.Parent=app.PhytoplanktonidentificationTab;
                app.ContinueexistingeffortCheckBox.Position = [905 605 207 22];

            end
            
            if app.TabGroup.SelectedTab==app.ZooplanktonidentificationTab
                app.ImagePanel.Parent=app.ZooplanktonidentificationTab;
                app.outofLabel.Parent=app.ZooplanktonidentificationTab;
                app.ImagesleftEditField.Parent=app.ZooplanktonidentificationTab;
                app.ImagesleftEditField_2Label.Parent=app.ZooplanktonidentificationTab;
                app.GoBackButton.Parent=app.ZooplanktonidentificationTab;
                
                app.SelectpathtotheimagefolderButton.BackgroundColor = [0.96,0.88,0.69];
                app.SelectpathtotheimagefolderButton.Parent=app.ZooplanktonidentificationTab;

                app.StartneweffortCheckBox.Parent=app.ZooplanktonidentificationTab;
                app.StartneweffortCheckBox.Position = [723 605 143 22];
                app.ContinueexistingeffortCheckBox.Parent=app.ZooplanktonidentificationTab;
                app.ContinueexistingeffortCheckBox.Position = [905 605 207 22];

            end
            
        end

        % Button pushed function: SpecifyButton, SpecifyButton_2
        function SpecifyButtonPushed(app, event)
          if app.TabGroup.SelectedTab==app.PhytoplanktonidentificationTab
             other_species = sort({'Alexandrium','Ceratium_fusus','Ceratium_other','Gymnodinium','Protoperidinium','Torodinium','Phaeocystis','Chaetoceros_socialis','Chaetoceros','Guinardia','Eucampia','Hemiaulus','Licmophora','Rhizosolenia_Proboscia','Thalassionema_Thalassiothrix','Thalassiosira','Stephanopyxis','Leptocylindrus','Bacteriastrum'});
          end
          if app.TabGroup.SelectedTab==app.ZooplanktonidentificationTab
             other_species = sort({'Polychaete','Pteropod','Ostracod','Doliolid','Salp','Chaetognath','Diatom chain','Noctiluca'});
          end
            
            [idx, tf] = listdlg('ListString', other_species,...
                'SelectionMode', 'Single', 'PromptString', 'Select item', 'Initialvalue', 1,'Name', 'Make choice');
           
            if tf
                app.Handles.class =other_species(idx);
                AfterClassButtonPressed(app) 
            else
                % user canceled or closed dialog
            end
           
        end

        % Button pushed function: AkashiwoAButton, Amphipod1Button, 
        % Appendicularian2Button, CalanoidAButton, 
        % CantidentifyXButton, CantidentifyXButton_2, 
        % CeratiumfurcaFButton, ChattonellaHButton, 
        % CiliatesSButton, Cladoceran3Button, CochlodiniumCButton, 
        % Copepod4Button, CyclopoidBButton, DinophysisDButton, 
        % Euphausiid5Button, GyrodiniumGButton, 
        % HarpacticoidCButton, LingulodiniumLButton, 
        % NOcantidentifyXButton, NOother0Button, NaupliiNButton, 
        % Other0Button, Other0Button_2, ProrocentrumPButton, 
        % YESYButton
        function ClassButtonPushed(app, event)
            
            if strcmp(event.EventName,'ButtonPushed')
                tmp=event.Source.Text;
            end
 
            if strcmp(event.EventName,'KeyPress')
                    
                    key = upper(event.Character);
                    
                    if app.TabGroup.SelectedTab==app.TimeserieserrorquantificationTab
                        button_list={app.CorrectclassificationPanel.Children.Text}';
                    end
                    if app.TabGroup.SelectedTab==app.PhytoplanktonidentificationTab
                        button_list={app.ImageclassificationPanel.Children.Text}';
                    end
                    if app.TabGroup.SelectedTab==app.ZooplanktonidentificationTab
                        button_list={app.ImageclassificationPanel_ZOO.Children.Text}';
                    end
                    
                    tf= ~cellfun('isempty',strfind(button_list,strcat('(',key,')')));
                    tmp=char(button_list(tf)); 
                    
                    if app.TabGroup.SelectedTab==app.TimeserieserrorquantificationTab
                        app.CorrectclassificationPanel.Children(tf).BackgroundColor = [0.7 0.7 0.7];
                        pause(0.1)
                        app.CorrectclassificationPanel.Children(tf).BackgroundColor = [0.96 0.96 0.96];
                    end
                    if app.TabGroup.SelectedTab==app.PhytoplanktonidentificationTab
                        app.ImageclassificationPanel.Children(tf).BackgroundColor = [0.7 0.7 0.7];
                        pause(0.1)
                        app.ImageclassificationPanel.Children(tf).BackgroundColor = [0.96 0.96 0.96];
                    end
                    if app.TabGroup.SelectedTab==app.ZooplanktonidentificationTab
                        app.ImageclassificationPanel_ZOO.Children(tf).BackgroundColor = [0.7 0.7 0.7];
                        pause(0.1)
                        app.ImageclassificationPanel_ZOO.Children(tf).BackgroundColor = [0.96 0.96 0.96];
                    end
                    clear tf
            end
                
                app.Handles.class = cellstr(tmp(1:end-4));              
                if strcmp(tmp,'YES (Y)')
                   app.Handles.class = {'correct'};
                end
                if strcmp(tmp,'Other (0)') || strcmp(tmp,'NO - other (0)')
                   app.Handles.class = {'other'};
                end
                if strcmp(tmp,'Can''t identify (X)') || strcmp(tmp,'NO - can''t identify (X)')
                   app.Handles.class = {'unidentified'};
                end
                
                AfterClassButtonPressed(app)   

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.Tab = uitab(app.TabGroup);
            app.TimeserieserrorquantificationTab = uitab(app.TabGroup);
            app.TimeserieserrorquantificationTab.Title = 'Time-series error quantification';
            app.SelectOrganismButton = uibutton(app.TimeserieserrorquantificationTab, 'push');
            app.SelectOrganismButton.ButtonPushedFcn = createCallbackFcn(app, @SelectOrganismButtonPushed, true);
            app.SelectOrganismButton.Visible = 'off';
            app.SelectOrganismButton.Text = 'Select Organism';
            app.Species_label = uilabel(app.TimeserieserrorquantificationTab);
            app.Species_label.Text = '';
            app.StartneweffortCheckBox = uicheckbox(app.TimeserieserrorquantificationTab);
            app.StartneweffortCheckBox.ValueChangedFcn = createCallbackFcn(app, @StartneweffortCheckBoxValueChanged, true);
            app.StartneweffortCheckBox.Visible = 'off';
            app.StartneweffortCheckBox.Text = 'Start new effort';
            app.ContinueexistingeffortCheckBox = uicheckbox(app.TimeserieserrorquantificationTab);
            app.ContinueexistingeffortCheckBox.ValueChangedFcn = createCallbackFcn(app, @ContinueexistingeffortCheckBoxValueChanged, true);
            app.ContinueexistingeffortCheckBox.Visible = 'off';
            app.ContinueexistingeffortCheckBox.Text = 'Continue existing effort';
            app.CorrectclassificationPanel = uipanel(app.TimeserieserrorquantificationTab);
            app.CorrectclassificationPanel.Title = 'Correct classification?';
            app.CorrectclassificationPanel.Visible = 'off';
            app.YESYButton = uibutton(app.CorrectclassificationPanel, 'push');
            app.YESYButton.ButtonPushedFcn = createCallbackFcn(app, @ClassButtonPushed, true);
            app.YESYButton.Text = 'YES (Y)';
            app.NOother0Button = uibutton(app.CorrectclassificationPanel, 'push');
            app.NOother0Button.ButtonPushedFcn = createCallbackFcn(app, @ClassButtonPushed, true);
            app.NOother0Button.Text = 'NO - other (0)';
            app.NOcantidentifyXButton = uibutton(app.CorrectclassificationPanel, 'push');
            app.NOcantidentifyXButton.ButtonPushedFcn = createCallbackFcn(app, @ClassButtonPushed, true);
            app.NOcantidentifyXButton.Text = 'NO - can''t identify (X)';
            app.outofLabel = uilabel(app.TimeserieserrorquantificationTab);
            app.outofLabel.Visible = 'off';
            app.outofLabel.Text = 'out of';
            app.GoBackButton = uibutton(app.TimeserieserrorquantificationTab, 'push');
            app.GoBackButton.ButtonPushedFcn = createCallbackFcn(app, @GoBackButtonPushed, true);
            app.GoBackButton.Visible = 'off';
            app.GoBackButton.Text = '<<< Go Back';
            app.SelectpathtoimagefoldersButton = uibutton(app.TimeserieserrorquantificationTab, 'state');
            app.SelectpathtoimagefoldersButton.ValueChangedFcn = createCallbackFcn(app, @SelectpathtoimagefoldersButtonValueChanged, true);
            app.SelectpathtoimagefoldersButton.Icon = 'FolderIcon.png';
            app.SelectpathtoimagefoldersButton.Text = {'Select path to'; 'image folders'};
            app.ImagesleftEditField_2Label = uilabel(app.TimeserieserrorquantificationTab);
            app.ImagesleftEditField_2Label.Visible = 'off';
            app.ImagesleftEditField_2Label.Text = 'Images left';
            app.ImagesleftEditField = uieditfield(app.TimeserieserrorquantificationTab, 'numeric');
            app.ImagesleftEditField.ValueDisplayFormat = '%.0f';
            app.ImagesleftEditField.Visible = 'off';
            app.ImagePanel = uipanel(app.TimeserieserrorquantificationTab);
            app.ImagePanel.Visible = 'off';
            app.Image = uiimage(app.ImagePanel);
            app.Img_ID = uilabel(app.ImagePanel);
            app.Img_ID.Text = 'Image ID: ';
            app.PhytoplanktonidentificationTab = uitab(app.TabGroup);
            app.PhytoplanktonidentificationTab.Title = 'Phytoplankton identification';
            app.SelectpathtotheimagefolderButton = uibutton(app.PhytoplanktonidentificationTab, 'state');
            app.SelectpathtotheimagefolderButton.ValueChangedFcn = createCallbackFcn(app, @SelectpathtotheimagefolderButtonValueChanged, true);
            app.SelectpathtotheimagefolderButton.Icon = 'FolderIcon.png';
            app.SelectpathtotheimagefolderButton.Text = {'Select path to'; 'the image folder'};
            app.ImageclassificationPanel = uipanel(app.PhytoplanktonidentificationTab);
            app.ImageclassificationPanel.Title = 'Image classification';
            app.ImageclassificationPanel.Visible = 'off';
            app.Other0Button = uibutton(app.ImageclassificationPanel, 'push');
            app.Other0Button.ButtonPushedFcn = createCallbackFcn(app, @ClassButtonPushed, true);
            app.Other0Button.Text = 'Other (0)';
            app.CantidentifyXButton = uibutton(app.ImageclassificationPanel, 'push');
            app.CantidentifyXButton.ButtonPushedFcn = createCallbackFcn(app, @ClassButtonPushed, true);
            app.CantidentifyXButton.Text = 'Can''t identify (X)';
            app.SpecifyButton = uibutton(app.ImageclassificationPanel, 'push');
            app.SpecifyButton.ButtonPushedFcn = createCallbackFcn(app, @SpecifyButtonPushed, true);
            app.SpecifyButton.Text = 'Specify ...';
            app.LingulodiniumLButton = uibutton(app.ImageclassificationPanel, 'push');
            app.LingulodiniumLButton.ButtonPushedFcn = createCallbackFcn(app, @ClassButtonPushed, true);
            app.LingulodiniumLButton.Text = 'Lingulodinium (L)';
            app.DinophysisDButton = uibutton(app.ImageclassificationPanel, 'push');
            app.DinophysisDButton.ButtonPushedFcn = createCallbackFcn(app, @ClassButtonPushed, true);
            app.DinophysisDButton.Text = 'Dinophysis (D)';
            app.AkashiwoAButton = uibutton(app.ImageclassificationPanel, 'push');
            app.AkashiwoAButton.ButtonPushedFcn = createCallbackFcn(app, @ClassButtonPushed, true);
            app.AkashiwoAButton.Text = 'Akashiwo (A)';
            app.CiliatesSButton = uibutton(app.ImageclassificationPanel, 'push');
            app.CiliatesSButton.ButtonPushedFcn = createCallbackFcn(app, @ClassButtonPushed, true);
            app.CiliatesSButton.Text = 'Ciliates (S)';
            app.NaupliiNButton = uibutton(app.ImageclassificationPanel, 'push');
            app.NaupliiNButton.ButtonPushedFcn = createCallbackFcn(app, @ClassButtonPushed, true);
            app.NaupliiNButton.Text = 'Nauplii (N)';
            app.ChattonellaHButton = uibutton(app.ImageclassificationPanel, 'push');
            app.ChattonellaHButton.ButtonPushedFcn = createCallbackFcn(app, @ClassButtonPushed, true);
            app.ChattonellaHButton.Text = 'Chattonella (H)';
            app.CeratiumfurcaFButton = uibutton(app.ImageclassificationPanel, 'push');
            app.CeratiumfurcaFButton.ButtonPushedFcn = createCallbackFcn(app, @ClassButtonPushed, true);
            app.CeratiumfurcaFButton.Text = 'Ceratium furca (F)';
            app.CochlodiniumCButton = uibutton(app.ImageclassificationPanel, 'push');
            app.CochlodiniumCButton.ButtonPushedFcn = createCallbackFcn(app, @ClassButtonPushed, true);
            app.CochlodiniumCButton.Text = 'Cochlodinium (C)';
            app.GyrodiniumGButton = uibutton(app.ImageclassificationPanel, 'push');
            app.GyrodiniumGButton.ButtonPushedFcn = createCallbackFcn(app, @ClassButtonPushed, true);
            app.GyrodiniumGButton.Text = 'Gyrodinium (G)';
            app.ProrocentrumPButton = uibutton(app.ImageclassificationPanel, 'push');
            app.ProrocentrumPButton.ButtonPushedFcn = createCallbackFcn(app, @ClassButtonPushed, true);
            app.ProrocentrumPButton.Text = 'Prorocentrum (P)';
            app.PolikrikosKButton = uibutton(app.ImageclassificationPanel, 'push');
            app.PolikrikosKButton.Text = 'Polikrikos (K)';
            app.PseudonitzshiaZButton = uibutton(app.ImageclassificationPanel, 'push');
            app.PseudonitzshiaZButton.Text = 'Pseudo-nitzshia (Z)';
            app.ZooplanktonidentificationTab = uitab(app.TabGroup);
            app.ZooplanktonidentificationTab.Title = 'Zooplankton identification';
            app.ImageclassificationPanel_ZOO = uipanel(app.ZooplanktonidentificationTab);
            app.ImageclassificationPanel_ZOO.Title = 'Image classification';
            app.ImageclassificationPanel_ZOO.Visible = 'off';
            app.Other0Button_2 = uibutton(app.ImageclassificationPanel_ZOO, 'push');
            app.Other0Button_2.ButtonPushedFcn = createCallbackFcn(app, @ClassButtonPushed, true);
            app.Other0Button_2.Text = 'Other (0)';
            app.CantidentifyXButton_2 = uibutton(app.ImageclassificationPanel_ZOO, 'push');
            app.CantidentifyXButton_2.ButtonPushedFcn = createCallbackFcn(app, @ClassButtonPushed, true);
            app.CantidentifyXButton_2.Text = 'Can''t identify (X)';
            app.SpecifyButton_2 = uibutton(app.ImageclassificationPanel_ZOO, 'push');
            app.SpecifyButton_2.ButtonPushedFcn = createCallbackFcn(app, @SpecifyButtonPushed, true);
            app.SpecifyButton_2.Text = 'Specify ...';
            app.CalanoidAButton = uibutton(app.ImageclassificationPanel_ZOO, 'push');
            app.CalanoidAButton.ButtonPushedFcn = createCallbackFcn(app, @ClassButtonPushed, true);
            app.CalanoidAButton.Text = 'Calanoid (A)';
            app.Euphausiid5Button = uibutton(app.ImageclassificationPanel_ZOO, 'push');
            app.Euphausiid5Button.ButtonPushedFcn = createCallbackFcn(app, @ClassButtonPushed, true);
            app.Euphausiid5Button.Text = 'Euphausiid (5)';
            app.Copepod4Button = uibutton(app.ImageclassificationPanel_ZOO, 'push');
            app.Copepod4Button.ButtonPushedFcn = createCallbackFcn(app, @ClassButtonPushed, true);
            app.Copepod4Button.Text = 'Copepod (4)';
            app.Amphipod1Button = uibutton(app.ImageclassificationPanel_ZOO, 'push');
            app.Amphipod1Button.ButtonPushedFcn = createCallbackFcn(app, @ClassButtonPushed, true);
            app.Amphipod1Button.Text = 'Amphipod (1)';
            app.Appendicularian2Button = uibutton(app.ImageclassificationPanel_ZOO, 'push');
            app.Appendicularian2Button.ButtonPushedFcn = createCallbackFcn(app, @ClassButtonPushed, true);
            app.Appendicularian2Button.Text = 'Appendicularian (2)';
            app.Cladoceran3Button = uibutton(app.ImageclassificationPanel_ZOO, 'push');
            app.Cladoceran3Button.ButtonPushedFcn = createCallbackFcn(app, @ClassButtonPushed, true);
            app.Cladoceran3Button.Text = 'Cladoceran (3)';
            app.CyclopoidBButton = uibutton(app.ImageclassificationPanel_ZOO, 'push');
            app.CyclopoidBButton.ButtonPushedFcn = createCallbackFcn(app, @ClassButtonPushed, true);
            app.CyclopoidBButton.Text = 'Cyclopoid (B)';
            app.HarpacticoidCButton = uibutton(app.ImageclassificationPanel_ZOO, 'push');
            app.HarpacticoidCButton.ButtonPushedFcn = createCallbackFcn(app, @ClassButtonPushed, true);
            app.HarpacticoidCButton.Text = 'Harpacticoid (C)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Validation_app_Final_v3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end